classdef Denoise_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        helponimagedenoisePanel         matlab.ui.container.Panel
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        SingleimageprocessingTab        matlab.ui.container.Tab
        currentmoduleimagedenoisePanel  matlab.ui.container.Panel
        imageButtonGroup                matlab.ui.container.ButtonGroup
        imagetodenoiseButton            matlab.ui.control.ToggleButton
        image_to_denoise_EditField      matlab.ui.control.EditField
        pathofsaveimageButton           matlab.ui.control.ToggleButton
        path_of_save_image_EditField    matlab.ui.control.EditField
        startButton                     matlab.ui.control.Button
        saveparametersPanel             matlab.ui.container.Panel
        saveallButton                   matlab.ui.control.Button
        outputfilenameLabel             matlab.ui.control.Label
        filename_prefix_EditField       matlab.ui.control.EditField
        BatchimageprocessingTab         matlab.ui.container.Tab
        currentmoduleimagedenoisePanel_2  matlab.ui.container.Panel
        imageButtonGroup_2              matlab.ui.container.ButtonGroup
        pathofinputimagesButton         matlab.ui.control.ToggleButton
        image_to_denoise_EditField_2    matlab.ui.control.EditField
        pathofsaveimagesButton          matlab.ui.control.ToggleButton
        path_of_save_image_EditField_2  matlab.ui.control.EditField
        startButton_2                   matlab.ui.control.Button
        saveparametersPanel_2           matlab.ui.container.Panel
        saveallButton_2                 matlab.ui.control.Button
        outputprefixLabel_2             matlab.ui.control.Label
        filename_prefix_EditField_2     matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        input_img_path
        output_img
        output_prefix
        output_path
        input_dir_path
        is_save_mask
        
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";         
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.k_means_param_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to denoise'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_denoise_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_denoise_EditField.Value;
                case 'path of save image'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField.Value=app.output_path;
               
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            info=load_untouch_nii(app.input_img_path);
            img=info.img;
            hdr=info.hdr;
            img=double(img);
            app.output_img=nonlocalMeansDenoise_test(3,img);
            app.output_img=make_nii(app.output_img);
            app.output_img.hdr=hdr;
            path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            save_nii(app.output_img,path);       
% 弹出结束提示

            uialert(app.UIFigure,"Image Denoise is complete and the output image is saved in the folder!",'Notice','Icon','success')
             
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.output_prefix=app.filename_prefix_EditField.Value;   
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
        end

        % Selection changed function: imageButtonGroup_2
        function imageButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.imageButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'path of input images'
                    app.input_dir_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.image_to_denoise_EditField_2.Value=app.input_dir_path;
                case 'path of save images'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField_2.Value=app.output_path;
            end
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.output_prefix=app.filename_prefix_EditField.Value;   
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')

        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            file_list=dir(app.input_dir_path);
            %进度条显示处理进度
            progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
            for i=1:length(file_list)%循环处理图像
                if i==1%跳过前两个空文件夹
                    continue
                elseif i==2
                    continue
                end
                file_name=fullfile(app.input_dir_path,file_list(i).name);
                
                info=load_untouch_nii(file_name);
                img=info.img;
                hdr=info.hdr;
                img=double(img);
                app.output_img=nonlocalMeansDenoise_test(3,img);
                app.output_img=make_nii(app.output_img);
                app.output_img.hdr=hdr;
                path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
                save_nii(app.output_img,path);   
                
                progress_bar.Value=(i-2)/(length(file_list)-2);
                if i~=length(file_list)
                    progress_bar.Message=sprintf('Working on image %d',i-1);
                else%关闭进度条
                    close(progress_bar);
                end
            end
            uialert(app.UIFigure,['Image denoise is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponimagedenoisePanel = uipanel(app.UIFigure);
            app.helponimagedenoisePanel.Title = 'help on image denoise';
            app.TextAreaLabel = uilabel(app.helponimagedenoisePanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponimagedenoisePanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmoduleimagedenoisePanel = uipanel(app.SingleimageprocessingTab);
            app.currentmoduleimagedenoisePanel.Title = 'current module:image denoise';
            app.imageButtonGroup = uibuttongroup(app.currentmoduleimagedenoisePanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetodenoiseButton = uitogglebutton(app.imageButtonGroup);
            app.imagetodenoiseButton.Text = 'image to denoise';
            app.imagetodenoiseButton.Value = true;
            app.image_to_denoise_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmoduleimagedenoisePanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveparametersPanel = uipanel(app.currentmoduleimagedenoisePanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.outputfilenameLabel = uilabel(app.saveparametersPanel);
            app.outputfilenameLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmoduleimagedenoisePanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmoduleimagedenoisePanel_2.Title = 'current module:image denoise';
            app.imageButtonGroup_2 = uibuttongroup(app.currentmoduleimagedenoisePanel_2);
            app.imageButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroup_2SelectionChanged, true);
            app.imageButtonGroup_2.Title = 'image';
            app.pathofinputimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.pathofinputimagesButton.Value = true;
            app.image_to_denoise_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.pathofsaveimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofsaveimagesButton.Text = 'path of save images';
            app.path_of_save_image_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.startButton_2 = uibutton(app.currentmoduleimagedenoisePanel_2, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.saveparametersPanel_2 = uipanel(app.currentmoduleimagedenoisePanel_2);
            app.saveparametersPanel_2.Title = 'save parameters';
            app.saveallButton_2 = uibutton(app.saveparametersPanel_2, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            app.outputprefixLabel_2 = uilabel(app.saveparametersPanel_2);
            app.outputprefixLabel_2.Text = 'output prefix';
            app.filename_prefix_EditField_2 = uieditfield(app.saveparametersPanel_2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Denoise_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end