classdef FCM_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        helponfastfuzzyCmeansPanel    matlab.ui.container.Panel
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        SingleimageprocessingTab      matlab.ui.container.Tab
        currentmodulefastfuzzyCmeansPanel  matlab.ui.container.Panel
        imageButtonGroup              matlab.ui.container.ButtonGroup
        imagetofuzzyCmeansButton      matlab.ui.control.ToggleButton
        image_to_fuzzy_C_means_EditField  matlab.ui.control.EditField
        pathofsaveimageButton         matlab.ui.control.ToggleButton
        path_of_save_image_EditField  matlab.ui.control.EditField
        startButton                   matlab.ui.control.Button
        saveoutputButton              matlab.ui.control.Button
        FCMresultPanel                matlab.ui.container.Panel
        FCMsegmentationcentersEditFieldLabel  matlab.ui.control.Label
        FCMsegmentationcentersEditField  matlab.ui.control.EditField
        saveparametersPanel           matlab.ui.container.Panel
        FCMparammEditFieldLabel       matlab.ui.control.Label
        FCMparammEditField            matlab.ui.control.NumericEditField
        saveallButton                 matlab.ui.control.Button
        outputfilenameEditFieldLabel  matlab.ui.control.Label
        filename_prefix_EditField     matlab.ui.control.EditField
        BatchimageprocessingTab       matlab.ui.container.Tab
        currentmodulefastfuzzyCmeansPanel_2  matlab.ui.container.Panel
        startButton_2                 matlab.ui.control.Button
        parametersPanel               matlab.ui.container.Panel
        pathofinputimagesButton       matlab.ui.control.Button
        image_to_fcm_EditField        matlab.ui.control.EditField
        inputimageprefixLabel         matlab.ui.control.Label
        filename_prefix_EditField_4   matlab.ui.control.EditField
        path_of_input_images_Label    matlab.ui.control.Label
        input_name_Label              matlab.ui.control.Label
        output_prefix_Label           matlab.ui.control.Label
        traget_Label                  matlab.ui.control.Label
        FCMparammEditField_2Label     matlab.ui.control.Label
        FCMparammEditField_2          matlab.ui.control.NumericEditField
        saveallButton_2               matlab.ui.control.Button
        outputprefixLabel             matlab.ui.control.Label
        filename_prefix_EditField_2   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        input_img_path
        output_img
        output_prefix
        output_path
        m_value
        k_value
        membership_mat
        W_mat
        % batch
        inputs_prefix
        input_dir_path
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";       
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.k_means_param_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to fuzzy C-means'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_fuzzy_C_means_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_fuzzy_C_means_EditField.Value;
                case 'path of save image'
                    app.output_path = uigetdir();
                    app.path_of_save_image_EditField.Value=app.output_path;
               
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            info=load_untouch_nii(app.input_img_path);
            IMG=info.img;
            hdr=info.hdr;
%             K-means定初始聚类中心
            app.k_value=4;
%             [C_init] = kmeans_init(IMG,app.k_value,[],[]);
            img=int32(IMG(IMG~=0));
%             FCM
            [C,U,LUT,H]=FastFCMeans(img(:),app.k_value,app.m_value,false) ;% perform FCM segmentation
            disp('FCM segmentation centers are:' );
            disp(C);
            app.membership_mat=U;
            center='';
%             在前端打印centers
            for ii=1:length(C)
                center=[center,num2str(C(ii))];
                if ii~=length(C)
                    center=[center,','];
                end
            end
            app.FCMsegmentationcentersEditField.Value=center;
            L=LUT2label(img,LUT);
            % Get FCM results获得FCM处理后的结果
            FCM_seg=zeros(size(IMG));
            OriginId=find(IMG~=0);
            FCM_seg(OriginId(L(:)==app.k_value))=1;
            if app.k_value>3
                FCM_seg(OriginId(L(:)==app.k_value-1))=1;
            end
            % W is ratio of each class
            W = zeros(1,app.k_value-1);
            for c = 1:4
                W(c) = length(find(L(:) == c)) / numel(IMG(IMG ~= 0));
            end
            app.W_mat=W;
            app.output_img=make_nii(FCM_seg);
            app.output_img.hdr = hdr; 
            uialert(app.UIFigure,"FCM segmentation is complete, please check the cluster" + ...
                " centre to decide whether to save the image, to save the image click the button'save output'.",'Notice','Icon','success')
            
            
        end

        % Button pushed function: saveoutputButton
        function saveoutputButtonPushed(app, event)
            path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            save_nii(app.output_img, path);
            path=[app.output_path,'\',app.output_prefix,'_membership.mat'];
            membership=app.membership_mat;
            save(path,'membership');
            path=[app.output_path,'\',app.output_prefix,'_radio_W.mat'];
            radio_W=app.W_mat;
            save(path,'radio_W');
            uialert(app.UIFigure,'The image,radio_W and the membership matrix are saved and can be viewed in the corresponding path.','Notice','Icon','success')
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.m_value=app.FCMparammEditField.Value;
            app.output_prefix=app.filename_prefix_EditField.Value;        
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
                
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.m_value=app.FCMparammEditField_2.Value;
            app.output_prefix=app.filename_prefix_EditField_2.Value; 
            app.inputs_prefix=app.filename_prefix_EditField_4.Value;%非正则表达式
            app.inputs_prefix=regexptranslate("escape",app.inputs_prefix);%转成正则表达式
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            
            [files_name,files_path]=file_extraction(app.input_dir_path, app.inputs_prefix);
            if isempty(files_name)
                alart_message='There is not the images to be processed in the document!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(files_name)
                    file=files_name{i};
                    info=load_untouch_nii(file);
                    IMG=info.img;
                    hdr=info.hdr;
                    %FCM
                    app.k_value=4;
                    img=int32(IMG(IMG~=0));
                    [C,U,LUT,H]=FastFCMeans(img(:),app.k_value,app.m_value,false) ;% perform FCM segmentation
                    disp('FCM segmentation centers are:' );
                    disp(C);
                    
                    L=LUT2label(img,LUT);
                    % Get FCM results获得FCM处理后的结果
                    FCM_seg=zeros(size(IMG));
                    OriginId=find(IMG~=0);
                    FCM_seg(OriginId(L(:)==app.k_value))=1;
                    if app.k_value>3
                        FCM_seg(OriginId(L(:)==app.k_value-1))=1;
                    end
                    % W is ratio of each class
                    W = zeros(1,app.k_value-1);
                    for c = 1:4
                        W(c) = length(find(L(:) == c)) / numel(IMG(IMG ~= 0));
                    end
                    
                    app.output_img=make_nii(FCM_seg);
                    app.output_img.hdr = hdr; 
                    
                    [~, endindex]= regexp(file,app.inputs_prefix);
                    disp(file)
                    disp(endindex)
                    suffix = file(endindex+1:end);
                    save_path=fullfile(files_path{i},[app.output_prefix,suffix]);
                    save_nii(app.output_img, save_path);
                    sub_suffix=suffix(1:(end-7));% 去掉'.nii.gz'
                    path=fullfile(files_path{i},[app.output_prefix,sub_suffix,'_membership.mat']);
                    membership=U;
                    save(path,'membership');
                    path=fullfile(files_path{i},[app.output_prefix,sub_suffix,'_radio_W.mat']);
                    radio_W=W;
                    save(path,'radio_W');
                    %进度条更新
                    progress_bar.Value=i/length(files_name);
                    if i~=length(files_name)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
            end

            uialert(app.UIFigure,['FCM segmentation is complete,' ...
                'and the output are saved to the corresponding path.'],'Notice','Icon','success')
        
        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed(app, event)
            app.input_dir_path = uigetdir();
            app.image_to_fcm_EditField.Value=app.input_dir_path;
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponfastfuzzyCmeansPanel = uipanel(app.UIFigure);
            app.helponfastfuzzyCmeansPanel.Title = 'help on fast fuzzy C-means';
            app.TextAreaLabel = uilabel(app.helponfastfuzzyCmeansPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponfastfuzzyCmeansPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmodulefastfuzzyCmeansPanel = uipanel(app.SingleimageprocessingTab);
            app.currentmodulefastfuzzyCmeansPanel.Title = 'current module:fast fuzzy C-means';
            app.imageButtonGroup = uibuttongroup(app.currentmodulefastfuzzyCmeansPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetofuzzyCmeansButton = uitogglebutton(app.imageButtonGroup);
            app.imagetofuzzyCmeansButton.Text = 'image to fuzzy C-means';
            app.image_to_fuzzy_C_means_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.pathofsaveimageButton.Value = true;
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmodulefastfuzzyCmeansPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveoutputButton = uibutton(app.currentmodulefastfuzzyCmeansPanel, 'push');
            app.saveoutputButton.ButtonPushedFcn = createCallbackFcn(app, @saveoutputButtonPushed, true);
            app.saveoutputButton.Text = 'save output';
            app.FCMresultPanel = uipanel(app.currentmodulefastfuzzyCmeansPanel);
            app.FCMresultPanel.Title = 'FCM result';
            app.FCMsegmentationcentersEditFieldLabel = uilabel(app.FCMresultPanel);
            app.FCMsegmentationcentersEditFieldLabel.Text = 'FCM segmentation centers';
            app.FCMsegmentationcentersEditField = uieditfield(app.FCMresultPanel, 'text');
            app.saveparametersPanel = uipanel(app.currentmodulefastfuzzyCmeansPanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.FCMparammEditFieldLabel = uilabel(app.saveparametersPanel);
            app.FCMparammEditFieldLabel.Text = 'FCM param:m     ';
            app.FCMparammEditField = uieditfield(app.saveparametersPanel, 'numeric');
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.outputfilenameEditFieldLabel = uilabel(app.saveparametersPanel);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmodulefastfuzzyCmeansPanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmodulefastfuzzyCmeansPanel_2.Title = 'current module:fast fuzzy C-means';
            app.startButton_2 = uibutton(app.currentmodulefastfuzzyCmeansPanel_2, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.parametersPanel = uipanel(app.currentmodulefastfuzzyCmeansPanel_2);
            app.parametersPanel.Title = 'parameters';
            app.pathofinputimagesButton = uibutton(app.parametersPanel, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.image_to_fcm_EditField = uieditfield(app.parametersPanel, 'text');
            app.inputimageprefixLabel = uilabel(app.parametersPanel);
            app.inputimageprefixLabel.Text = 'input image prefix';
            app.filename_prefix_EditField_4 = uieditfield(app.parametersPanel, 'text');
            app.path_of_input_images_Label = uilabel(app.parametersPanel);
            app.path_of_input_images_Label.Text = '选择批量图片集所在的大文件夹，图片集以子文件夹形式划分';
            app.input_name_Label = uilabel(app.parametersPanel);
            app.input_name_Label.Text = '待批量处理的图片文件名前缀';
            app.output_prefix_Label = uilabel(app.parametersPanel);
            app.output_prefix_Label.Text = '相关输出图像的前缀名，存放在输入图片所在的子文件夹';
            app.traget_Label = uilabel(app.parametersPanel);
            app.traget_Label.Text = '设置FCM的m参数';
            app.FCMparammEditField_2Label = uilabel(app.parametersPanel);
            app.FCMparammEditField_2Label.Text = 'FCM param:m     ';
            app.FCMparammEditField_2 = uieditfield(app.parametersPanel, 'numeric');
            app.saveallButton_2 = uibutton(app.parametersPanel, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            app.outputprefixLabel = uilabel(app.parametersPanel);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField_2 = uieditfield(app.parametersPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FCM_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end