classdef HD_BET_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        helponskullstrippingPanel       matlab.ui.container.Panel
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        SingleimageprocessingTab        matlab.ui.container.Tab
        currentmoduleskullstrippingPanel  matlab.ui.container.Panel
        imageButtonGroup                matlab.ui.container.ButtonGroup
        imagetoskullstrippingButton     matlab.ui.control.ToggleButton
        image_to_skull_stripping_EditField  matlab.ui.control.EditField
        pathofsaveimageButton           matlab.ui.control.ToggleButton
        path_of_save_image_EditField    matlab.ui.control.EditField
        startButton                     matlab.ui.control.Button
        saveparametersPanel             matlab.ui.container.Panel
        saveallButton                   matlab.ui.control.Button
        outputprefixLabel               matlab.ui.control.Label
        filename_prefix_EditField       matlab.ui.control.EditField
        save_maskDropDownLabel          matlab.ui.control.Label
        save_maskDropDown               matlab.ui.control.DropDown
        BatchimageprocessingTab         matlab.ui.container.Tab
        currentmoduleskullstrippingPanel_2  matlab.ui.container.Panel
        imageButtonGroup_2              matlab.ui.container.ButtonGroup
        pathofinputimagesButton         matlab.ui.control.ToggleButton
        image_to_skull_stripping_EditField_2  matlab.ui.control.EditField
        pathofsaveimagesButton          matlab.ui.control.ToggleButton
        path_of_save_image_EditField_2  matlab.ui.control.EditField
        startButton_2                   matlab.ui.control.Button
        saveparametersPanel_2           matlab.ui.container.Panel
        saveallButton_2                 matlab.ui.control.Button
        outputprefixLabel_2             matlab.ui.control.Label
        filename_prefix_EditField_2     matlab.ui.control.EditField
        save_maskDropDown_2Label        matlab.ui.control.Label
        save_maskDropDown_2             matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        input_img_path
        output_img
        output_prefix
        output_path
        
        is_save_mask
        
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";         
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.k_means_param_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to skull stripping'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_skull_stripping_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_skull_stripping_EditField.Value;
                case 'path of save image'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField.Value=app.output_path;
               
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            device=py.str('cpu');
            mode=py.str('fast');
            pp=py.int(1);
            overwrite_existing=py.int(1);
            input_path=py.str(app.input_img_path);
            app.output_img=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            app.output_img=py.str(app.output_img);
            py.HD_BET_start.HD_BET_run(input_path,app.output_img,mode,device,tta,app.is_save_mask,overwrite_existing,pp);
% 弹出结束提示
            switch app.save_maskDropDown.Value
                case 'yes'
                    uialert(app.UIFigure,"Skull stripping is complete and the brain image and mask are saved in the folder!",'Notice','Icon','success')
                case 'no'
                    uialert(app.UIFigure,"Skull stripping is complete and the brain image is saved in the folder!",'Notice','Icon','success')
            end 
             
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.output_prefix=app.filename_prefix_EditField.Value;   
            value = app.save_maskDropDown.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
                
        end

        % Value changed function: save_maskDropDown
        function save_maskDropDownValueChanged(app, event)
            value = app.save_maskDropDown.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
        end

        % Selection changed function: imageButtonGroup_2
        function imageButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.imageButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'path of input images'
                    app.input_dir_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.image_to_skull_stripping_EditField_2.Value=app.input_dir_path;
                case 'path of save images'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField_2.Value=app.output_path;
               
            end
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.output_prefix=app.filename_prefix_EditField.Value;   
            value = app.save_maskDropDown.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            file_list=dir(app.input_dir_path);
%             参数
            device=py.str('cpu');
            mode=py.str('fast');
            pp=py.int(1);
            overwrite_existing=py.int(1);
            %进度条显示处理进度
            progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
            for i=1:length(file_list)%循环处理图像
                if i==1%跳过前两个空文件夹
                    continue
                elseif i==2
                    continue
                end
                file_name=fullfile(app.input_dir_path,file_list(i).name);
                input_path=py.str(file_name);
                app.output_img=[app.output_path,'\',app.output_prefix,'_',file_list(i).name,'.nii.gz'];
                app.output_img=py.str(app.output_img);
                py.HD_BET_start.HD_BET_run(input_path,app.output_img,mode,device,tta,app.is_save_mask,overwrite_existing,pp);

%                 uialert(app.UIFigure,"FCM segmentation is complete, please check the cluster" + ...
%                     " centre to decide whether to save the image, to save the image click the button'save output'.",'Notice','Icon','success')
                
                progress_bar.Value=(i-2)/(length(file_list)-2);
                if i~=length(file_list)
                    progress_bar.Message=sprintf('Working on image %d',i-1);
                else%关闭进度条
                    close(progress_bar);
                end
            end
            uialert(app.UIFigure,['Skull stripping is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponskullstrippingPanel = uipanel(app.UIFigure);
            app.helponskullstrippingPanel.Title = 'help on skull-stripping';
            app.TextAreaLabel = uilabel(app.helponskullstrippingPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponskullstrippingPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmoduleskullstrippingPanel = uipanel(app.SingleimageprocessingTab);
            app.currentmoduleskullstrippingPanel.Title = 'current module:skull-stripping';
            app.imageButtonGroup = uibuttongroup(app.currentmoduleskullstrippingPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetoskullstrippingButton = uitogglebutton(app.imageButtonGroup);
            app.imagetoskullstrippingButton.Text = 'image to skull stripping';
            app.imagetoskullstrippingButton.Value = true;
            app.image_to_skull_stripping_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmoduleskullstrippingPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveparametersPanel = uipanel(app.currentmoduleskullstrippingPanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.outputprefixLabel = uilabel(app.saveparametersPanel);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.save_maskDropDownLabel = uilabel(app.saveparametersPanel);
            app.save_maskDropDownLabel.Text = 'save_mask';
            app.save_maskDropDown = uidropdown(app.saveparametersPanel);
            app.save_maskDropDown.Items = {'yes', 'no'};
            app.save_maskDropDown.ValueChangedFcn = createCallbackFcn(app, @save_maskDropDownValueChanged, true);
            app.save_maskDropDown.Value = 'yes';
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmoduleskullstrippingPanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmoduleskullstrippingPanel_2.Title = 'current module:skull-stripping';
            app.imageButtonGroup_2 = uibuttongroup(app.currentmoduleskullstrippingPanel_2);
            app.imageButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroup_2SelectionChanged, true);
            app.imageButtonGroup_2.Title = 'image';
            app.pathofinputimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.pathofinputimagesButton.Value = true;
            app.image_to_skull_stripping_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.pathofsaveimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofsaveimagesButton.Text = 'path of save images';
            app.path_of_save_image_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.startButton_2 = uibutton(app.currentmoduleskullstrippingPanel_2, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.saveparametersPanel_2 = uipanel(app.currentmoduleskullstrippingPanel_2);
            app.saveparametersPanel_2.Title = 'save parameters';
            app.saveallButton_2 = uibutton(app.saveparametersPanel_2, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            app.outputprefixLabel_2 = uilabel(app.saveparametersPanel_2);
            app.outputprefixLabel_2.Text = 'output prefix';
            app.filename_prefix_EditField_2 = uieditfield(app.saveparametersPanel_2, 'text');
            app.save_maskDropDown_2Label = uilabel(app.saveparametersPanel_2);
            app.save_maskDropDown_2Label.Text = 'save_mask';
            app.save_maskDropDown_2 = uidropdown(app.saveparametersPanel_2);
            app.save_maskDropDown_2.Items = {'yes', 'no'};
            app.save_maskDropDown_2.Value = 'yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HD_BET_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end