classdef MRF_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        helponfastfuzzyCmeansPanel      matlab.ui.container.Panel
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        SingleimageprocessingTab        matlab.ui.container.Tab
        currentmoduleMarkovRandomFieldPanel  matlab.ui.container.Panel
        imageButtonGroup                matlab.ui.container.ButtonGroup
        FCMsegmentationresultButton     matlab.ui.control.ToggleButton
        FCM_result_EditField            matlab.ui.control.EditField
        pathofsaveimageButton           matlab.ui.control.ToggleButton
        vessel_enhance_result_EditField  matlab.ui.control.EditField
        vesselenhanceresultButton       matlab.ui.control.ToggleButton
        path_of_save_image_EditField    matlab.ui.control.EditField
        histogrammatchedimageButton     matlab.ui.control.ToggleButton
        histogram_matched_image_EditField  matlab.ui.control.EditField
        startButton                     matlab.ui.control.Button
        saveparametersPanel             matlab.ui.container.Panel
        saveallButton                   matlab.ui.control.Button
        outputfilenameEditFieldLabel    matlab.ui.control.Label
        filename_prefix_EditField       matlab.ui.control.EditField
        matofclassmembershipsButton     matlab.ui.control.Button
        matofradioWButton               matlab.ui.control.Button
        U_EditField                     matlab.ui.control.EditField
        W_EditField                     matlab.ui.control.EditField
        BatchimageprocessingTab         matlab.ui.container.Tab
        currentmoduleMarkovRandomFieldPanel_2  matlab.ui.container.Panel
        imageButtonGroup_2              matlab.ui.container.ButtonGroup
        pathofinputimagesButton         matlab.ui.control.ToggleButton
        image_to_fuzzy_C_means_EditField_2  matlab.ui.control.EditField
        pathofsaveimagesButton          matlab.ui.control.ToggleButton
        path_of_save_image_EditField_2  matlab.ui.control.EditField
        startandsaveButton              matlab.ui.control.Button
        FCMresultPanel_2                matlab.ui.container.Panel
        FCMsegmentationcentersEditField_2Label  matlab.ui.control.Label
        FCMsegmentationcentersEditField_2  matlab.ui.control.EditField
        saveparametersPanel_2           matlab.ui.container.Panel
        FCMparammEditField_2Label       matlab.ui.control.Label
        FCMparammEditField_2            matlab.ui.control.NumericEditField
        saveallButton_2                 matlab.ui.control.Button
        outputprefixLabel               matlab.ui.control.Label
        filename_prefix_EditField_2     matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        FCM_result_path
        VE_result_path
        output_img
        output_prefix
        output_path
        input_img_path
        membership_mat
        W_mat
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";       
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.k_means_param_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'FCM segmentation result'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.FCM_result_EditField.Value=[path,filename];
                    app.FCM_result_path=app.FCM_result_EditField.Value;
                case 'vessel enhance result'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.vessel_enhance_result_EditField.Value=[path,filename];
                    app.VE_result_path=app.vessel_enhance_result_EditField.Value;
                case 'path of save image'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.vessel_enhance_result_EditField.Value=app.output_path;
                case 'histogram matched image'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.histogram_matched_image_EditField.Value=[path,filename];
                    app.input_img_path=app.histogram_matched_image_EditField.Value;
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            info_FCM=load_untouch_nii(app.FCM_result_path);
            FCM_seg=info_FCM.img;
            FCM_hdr=info_FCM.hdr;
            info_VE=load_untouch_nii(app.VE_result_path);
            vesselness=info_VE.img;
            VE_hdr=info_VE.hdr;
            info=load_untouch_nii(app.input_img_path);
            IMG=info.img;
            hdr=info.hdr;
            
            beta = 0.2;
            IL = 10;
            [MRF_seg] = MRF(FCM_seg, IMG, vesselness, U, beta, W, IL);
            
            %% remove little islands
            [MRF_seg_refine, ~, ~] = Connection_Judge_3D(MRF_seg, 0, [], 20, 3);

            app.output_img=make_nii(MRF_seg_refine);
            app.output_img.hdr = hdr; 
            path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            save_nii(app.output_img, path);
            uialert(app.UIFigure,"MRF segmentation is complete, The image " + ...
                "is saved and can be viewed in the corresponding path.",'Notice','Icon','success')
            
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.m_value=app.MRFparambetaEditField.Value;
            app.output_prefix=app.filename_prefix_EditField.Value;        
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
                
        end

        % Selection changed function: imageButtonGroup_2
        function imageButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.imageButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'path of input images'
                    app.input_dir_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.image_to_fuzzy_C_means_EditField_2.Value=app.input_dir_path;
                case 'path of save images'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.vessel_enhance_result_EditField.Value=app.output_path;
               
            end
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.m_value=app.FCMparammEditField_2.Value;
            app.output_prefix=app.filename_prefix_EditField_2.Value;        
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
        end

        % Button pushed function: startandsaveButton
        function startandsaveButtonPushed(app, event)
            file_list=dir(app.input_dir_path);
            f=uifigure;%进度条显示处理进度
            progress_bar=uiprogressdlg(f,'Title','Please wait','Message','Working on image 1','Value',0);
            for i=1:length(file_list)%循环处理图像
                if i==1%跳过前两个空文件夹
                    continue
                elseif i==2
                    continue
                end
                file_name=fullfile(app.input_dir_path,file_list(i).name);
                info=load_untouch_nii(file_name);
                IMG=info.img;
                hdr=info.hdr;
    %             K-means定初始聚类中心
                app.k_value=4;
%                 [C_init] = kmeans_init(IMG,app.k_value,[],[]);
                img=int32(IMG(IMG~=0));
    %             FCM
                [C,U,LUT,H]=FastFCMeans(img(:),app.k_value,app.m_value,false) ;% perform FCM segmentation
                disp('FCM segmentation centers are:' );
                disp(C);
                app.membership_mat=U;
                center='';
                for ii=1:length(C)
                    center=[center,num2str(C(ii))];
                    if ii~=length(C)
                        center=[center,','];
                    end
                end
                app.FCMsegmentationcentersEditField_2.Value=center;
                L=LUT2label(img,LUT);
                % Get FCM results获得FCM处理后的结果
                FCM_seg=zeros(size(IMG));
                OriginId=find(IMG~=0);
                FCM_seg(OriginId(L(:)==app.k_value))=1;
                if app.k_value>3
                    FCM_seg(OriginId(L(:)==app.k_value-1))=1;
                end
                 W = zeros(1,K);
                for c = 1:4
                    W(c) = length(find(L(:) == c)) / numel(IMG(IMG ~= 0));
                end
                app.W_mat=W;
                app.output_img=make_nii(FCM_seg);
                app.output_img.hdr = hdr; 
                
                path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
                save_nii(app.output_img, path);
                path=[app.output_path,'\',app.output_prefix,'_membership.mat'];
                membership=app.membership_mat;
                save(path,'membership');
                path=[app.output_path,'\',app.output_prefix,'_radio_W.mat'];
                radio_W=app.W_mat;
                save(path,'radio_W');
%                 uialert(app.UIFigure,"FCM segmentation is complete, please check the cluster" + ...
%                     " centre to decide whether to save the image, to save the image click the button'save output'.",'Notice','Icon','success')
                
                progress_bar.Value=(i-2)/(length(file_list)-2);
                if i~=length(file_list)
                    progress_bar.Message=sprintf('Working on image %d',i-1);
                else%关闭进度条
                    close(progress_bar);
                end
            end
            uialert(app.UIFigure,['Histogram matching is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        
        end

        % Button pushed function: matofclassmembershipsButton
        function matofclassmembershipsButtonPushed(app, event)
            [filename,path]=uigetfile({'*.mat'},'选择mat文件');
            app.U_EditField.Value=[path,filename];
            app.membership_mat=importdata(app.U_EditField.Value);    
        end

        % Button pushed function: matofradioWButton
        function matofradioWButtonPushed(app, event)
            [filename,path]=uigetfile({'*.mat'},'选择mat文件');
            app.W_EditField.Value=[path,filename];
            app.W_mat=importdata(app.W_EditField.Value);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponfastfuzzyCmeansPanel = uipanel(app.UIFigure);
            app.helponfastfuzzyCmeansPanel.Title = 'help on fast fuzzy C-means';
            app.TextAreaLabel = uilabel(app.helponfastfuzzyCmeansPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponfastfuzzyCmeansPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmoduleMarkovRandomFieldPanel = uipanel(app.SingleimageprocessingTab);
            app.currentmoduleMarkovRandomFieldPanel.Title = 'current module:Markov Random Field';
            app.imageButtonGroup = uibuttongroup(app.currentmoduleMarkovRandomFieldPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.FCMsegmentationresultButton = uitogglebutton(app.imageButtonGroup);
            app.FCMsegmentationresultButton.Text = 'FCM segmentation result';
            app.FCMsegmentationresultButton.Value = true;
            app.FCM_result_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.vessel_enhance_result_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.vesselenhanceresultButton = uitogglebutton(app.imageButtonGroup);
            app.vesselenhanceresultButton.Text = 'vessel enhance result';
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.histogrammatchedimageButton = uitogglebutton(app.imageButtonGroup);
            app.histogrammatchedimageButton.Text = 'histogram matched image';
            app.histogram_matched_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmoduleMarkovRandomFieldPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveparametersPanel = uipanel(app.currentmoduleMarkovRandomFieldPanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.outputfilenameEditFieldLabel = uilabel(app.saveparametersPanel);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.matofclassmembershipsButton = uibutton(app.saveparametersPanel, 'push');
            app.matofclassmembershipsButton.ButtonPushedFcn = createCallbackFcn(app, @matofclassmembershipsButtonPushed, true);
            app.matofclassmembershipsButton.Text = 'mat of class memberships';
            app.matofradioWButton = uibutton(app.saveparametersPanel, 'push');
            app.matofradioWButton.ButtonPushedFcn = createCallbackFcn(app, @matofradioWButtonPushed, true);
            app.matofradioWButton.Text = 'mat of radio W';
            app.U_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.W_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmoduleMarkovRandomFieldPanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmoduleMarkovRandomFieldPanel_2.Title = 'current module:Markov Random Field';
            app.imageButtonGroup_2 = uibuttongroup(app.currentmoduleMarkovRandomFieldPanel_2);
            app.imageButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroup_2SelectionChanged, true);
            app.imageButtonGroup_2.Title = 'image';
            app.pathofinputimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.pathofinputimagesButton.Value = true;
            app.image_to_fuzzy_C_means_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.pathofsaveimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofsaveimagesButton.Text = 'path of save images';
            app.path_of_save_image_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.startandsaveButton = uibutton(app.currentmoduleMarkovRandomFieldPanel_2, 'push');
            app.startandsaveButton.ButtonPushedFcn = createCallbackFcn(app, @startandsaveButtonPushed, true);
            app.startandsaveButton.Text = 'start and save';
            app.FCMresultPanel_2 = uipanel(app.currentmoduleMarkovRandomFieldPanel_2);
            app.FCMresultPanel_2.Title = 'FCM result';
            app.FCMsegmentationcentersEditField_2Label = uilabel(app.FCMresultPanel_2);
            app.FCMsegmentationcentersEditField_2Label.Text = 'FCM segmentation centers';
            app.FCMsegmentationcentersEditField_2 = uieditfield(app.FCMresultPanel_2, 'text');
            app.saveparametersPanel_2 = uipanel(app.currentmoduleMarkovRandomFieldPanel_2);
            app.saveparametersPanel_2.Title = 'save parameters';
            app.FCMparammEditField_2Label = uilabel(app.saveparametersPanel_2);
            app.FCMparammEditField_2Label.Text = 'FCM param:m     ';
            app.FCMparammEditField_2 = uieditfield(app.saveparametersPanel_2, 'numeric');
            app.saveallButton_2 = uibutton(app.saveparametersPanel_2, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            app.outputprefixLabel = uilabel(app.saveparametersPanel_2);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField_2 = uieditfield(app.saveparametersPanel_2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRF_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end