classdef N4_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        helponN4FieldCorrectionPanel  matlab.ui.container.Panel
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        currentmoduleN4FieldCorrectionPanel  matlab.ui.container.Panel
        startButton                   matlab.ui.control.Button
        imagespathPanel               matlab.ui.container.Panel
        inputimagesTextAreaLabel      matlab.ui.control.Label
        inputimagesTextArea           matlab.ui.control.TextArea
        pathofinputimagesButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        %new
        input_images_paths%路径+文件名 字符向量元胞数组
        %new-end


    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
             if isempty(app.input_images_paths)
                alart_message='There is not the images to be processed!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(app.input_images_paths)
                    if endsWith(app.input_images_paths{i},'nii.gz')
                        input_img=gunzip(app.input_images_paths{i});
                        input_img=input_img{1};
                    else %.nii结尾
                        input_img=app.input_images_paths{i};
                    end
                 
                    bias_field_correct(input_img);
                    % 获取文件名
                    C=split(input_img,["/","\"]);
                    input_name=C(end);
                    disp(input_name)
                    [startindex,~]=regexp(input_img,input_name{1});
                    % 获取输入路径
                    input_path=input_img(1:startindex-1);
                    %输出的N4图像
                    output_name=fullfile(input_path,['m',input_name{1}]);
                    %对.nii打包成.nii.gz
                    output_image=gzip(output_name);
                    C=split(app.input_images_paths{i},'.');
                    save_path=strcat(C{1},'_N4.nii.gz');
                    % 把输出图像从输入路径里移动到输出路径，如果路径一致则改名
                    movefile(output_image{1},save_path);
                    progress_bar.Value=i/length(app.input_images_paths);
                    if i~=length(app.input_images_paths)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
                uialert(app.UIFigure,['Image denoise is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
             end


        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed2(app, event)
            [filename,file_path]=uigetfile({'*.nii.gz;*.nii'},'select One or More Files','MultiSelect', 'on');
            
            input_path_and_name=cell(1,length(filename));
            disp(filename)
            for i=1:length(filename)
                input_file_path=[file_path,filename{i}];
                input_path_and_name{i}=input_file_path;
            end
            
            app.input_images_paths=[app.input_images_paths,input_path_and_name];%包括了所有导入文件的路径+名字
            disp(app.input_images_paths)
            app.inputimagesTextArea.Value=app.input_images_paths;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponN4FieldCorrectionPanel = uipanel(app.UIFigure);
            app.helponN4FieldCorrectionPanel.Title = 'help on N4FieldCorrection';
            app.TextAreaLabel = uilabel(app.helponN4FieldCorrectionPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponN4FieldCorrectionPanel);
            app.currentmoduleN4FieldCorrectionPanel = uipanel(app.UIFigure);
            app.currentmoduleN4FieldCorrectionPanel.Title = 'current module:N4FieldCorrection';
            app.startButton = uibutton(app.currentmoduleN4FieldCorrectionPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.imagespathPanel = uipanel(app.currentmoduleN4FieldCorrectionPanel);
            app.imagespathPanel.Title = 'image(s) path';
            app.inputimagesTextAreaLabel = uilabel(app.imagespathPanel);
            app.inputimagesTextAreaLabel.Text = 'input image(s)';
            app.inputimagesTextArea = uitextarea(app.imagespathPanel);
            app.pathofinputimagesButton = uibutton(app.imagespathPanel, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed2, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = N4_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end