classdef data_process < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        StepbystepprocessingTab    matlab.ui.container.Tab
        imagepreprocessingPanel    matlab.ui.container.Panel
        skullstrippingButton       matlab.ui.control.Button
        imagedenoiseButton         matlab.ui.control.Button
        N4FieldCorrectionButton    matlab.ui.control.Button
        vesselenhancementButton    matlab.ui.control.Button
        BatchpreprocessingPanel    matlab.ui.container.Panel
        histogrammatchingButton    matlab.ui.control.Button
        vesselsegmentationPanel    matlab.ui.container.Panel
        FFCMMRFfastfuzzyCmeansMarkovRandomFieldButton  matlab.ui.control.Button
        MeasurementofvascularindicatorsTab  matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        SingleimageTab             matlab.ui.container.Tab
        MeasurementPanel           matlab.ui.container.Panel
        imagepathsButtonGroup      matlab.ui.container.ButtonGroup
        pathofsavestatButton       matlab.ui.control.ToggleButton
        pathofvascularimageButton  matlab.ui.control.ToggleButton
        pathofbrainmaskButton      matlab.ui.control.ToggleButton
        save_stat_EditField        matlab.ui.control.EditField
        vessel_EditField_2         matlab.ui.control.EditField
        brain_mask_EditField_3     matlab.ui.control.EditField
        TherangeofvascularstatisticsDropDownLabel  matlab.ui.control.Label
        TherangeofvascularstatisticsDropDown  matlab.ui.control.DropDown
        startButton_3              matlab.ui.control.Button
        MeasurementofvascularindifferentbrainnetworksPanel  matlab.ui.container.Panel
        pathofatlasButton          matlab.ui.control.Button
        path_atlas_EditField_2     matlab.ui.control.EditField
        saveallButton              matlab.ui.control.Button
        pathofatlastxtButton       matlab.ui.control.Button
        path_atlas_EditField_3     matlab.ui.control.EditField
        helpTextAreaLabel          matlab.ui.control.Label
        helpTextArea               matlab.ui.control.TextArea
        BatchimagesTab             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        hist_app % 调用hist的app
        FCM_app% 调用FCM的app
        skull_stripping_app% 调用skull_stripping的app
        VE_app% 调用vessel enhance的app
        Denoise_app %调用Denoise的app
        N4_app%调用N4的app
        MRF_app%调用MRF
        
        pre_processed_img_path%输入已预处理图像路径
        input_img_path%输入未预处理图像路径
        output_path%输出路径
        output_prefix%输出前缀
        is_save_mask%预处理保留mask
        
        m_value%FCM参数
        target_hist%目标直方图
        
        %one-step
        output_suffix
        input_dir_path
        outputs_prefix
        inputs_prefix
        output_img
        
        
        %血管指标测量
        seg_path
        stat_path
        brain_mask_path
        is_atlas
        
        atlas_prefix
        atlas_path
        atlas_ranges
        atlas_names_list
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MeasurementofvascularindifferentbrainnetworksPanel.Visible='off';
        end

        % Button pushed function: skullstrippingButton
        function skullstrippingButtonPushed(app, event)
            app.skull_stripping_app=HD_BET_parameter(app);
        end

        % Callback function
        function histogrammatchingDropDownValueChanged(app, event)
            value = app.histogrammatchingDropDown.Value;
            switch value
                case 'provided target hist'
                    app.hist_app=hist_parameter(app);
                case'user-defined target hist'
                    app.hist_app=hist_parameter_2(app); 
            end
        end

        % Button pushed function: vesselenhancementButton
        function vesselenhancementButtonPushed(app, event)
            app.VE_app=VE_parameter(app);
        end

        % Button pushed function: imagedenoiseButton
        function imagedenoiseButtonPushed(app, event)
            app.Denoise_app=Denoise_parameter(app);
        end

        % Button pushed function: N4FieldCorrectionButton
        function N4FieldCorrectionButtonPushed(app, event)
            app.N4_app=N4_parameter(app);
        end

        % Button pushed function: 
        % FFCMMRFfastfuzzyCmeansMarkovRandomFieldButton
        function FFCMMRFfastfuzzyCmeansMarkovRandomFieldButtonPushed(app, event)
            app.MRF_app=MRF_parameter(app);
        end

        % Callback function
        function pathofimagesButtonGroupSelectionChanged(app, event)
            selectedButton = app.pathofimagesButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'image to pre-processing'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.input_EditField.Value=[path,filename];
                    app.input_img_path=app.input_EditField.Value;
                case 'path of save images'
                    app.output_path = uigetdir();
                    app.output_EditField.Value=app.output_path;
               
            end
        end

        % Callback function
        function saveparamsButton_2Pushed(app, event)
            app.output_suffix=app.outputsuffixEditField_2.Value;   
            value = app.save_maskDropDown.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
              
        end

        % Callback function
        function startButtonPushed(app, event)
            
            %进度条显示处理进度
            progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on skull stripping','Value',0);
            
            %HD-BET
            input_path=py.str(app.input_img_path);
            skull_stripped_filename=fullfile(app.output_path,['Bet_',app.output_suffix,'.nii.gz']);
            skull_stripped=py.str(skull_stripped_filename);
            device=py.str('cpu');
            mode=py.str('fast');
            pp=py.int(1);
            overwrite_existing=py.int(1);
            tta=py.int(0);
            py.HD_BET_start.HD_BET_run(input_path,skull_stripped,mode,device,tta,app.is_save_mask,overwrite_existing,pp);
            %进度条更新
            progress_bar.Value=1/3;
            progress_bar.Message=sprintf('Working on denoising');
%             mask_filename=fullfile(app.output_path,[app.output_prefix,'_bet_mask.nii.gz']);%待删
            %Denoise
            info=load_untouch_nii(skull_stripped_filename);
            de_img=info.img;
            hdr=info.hdr;
            de_img=double(de_img);
            denoise_img=nonlocalMeansDenoise_test(3,de_img);
            denoise_save_img=make_nii(denoise_img);
            denoise_save_img.hdr=hdr;
            save_path=fullfile(app.output_path,['Denoise_',app.output_suffix,'.nii.gz']);
            save_nii(denoise_save_img,save_path);    
            %进度条更新
            progress_bar.Value=2/3;
            progress_bar.Message=sprintf('Working on N4FieldCorrection');
            %N4 待更新
            input_img=gunzip(save_path);
            input_img=input_img{1};
            bias_field_correct(input_img);
            % 获取文件名
            C=split(input_img,["/","\"]);
            input_name=C(end);
            [startindex,~]=regexp(input_img,input_name{1});
            % 获取输入路径
            input_path=input_img(1:startindex-1);
            %输出的N4图像
            output_name=fullfile(input_path,['m',input_name{1}]);
            %对.nii打包成.nii.gz
            app.output_img=gzip(output_name);
            % 把输出图像从输入路径里移动到输出路径，如果路径一致则改名
            stat=movefile(app.output_img,fullfile(app.output_path,[app.output_prefix,'.nii.gz']));
            progress_bar.Value=1;
            close(progress_bar);
 
            uialert(app.UIFigure,"Pre-processing is complete,the 'MRF_*.nii.gz' is the final result. " + ...
                "All images are saved in the folder!",'Notice','Icon','success')
            clear
        end

        % Callback function
        function pathofimagesButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.pathofimagesButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'image to segmentation'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.input_EditField_2.Value=[path,filename];
                    app.input_img_path=app.input_EditField_2.Value;
                case 'path of save images'
                    app.output_path = uigetdir();
                    app.output_EditField_2.Value=app.output_path;
            end
        end

        % Callback function
        function saveparamsButtonPushed(app, event)
            app.output_suffix=app.outputsuffixEditField.Value;  
            app.m_value=app.FCMparametermEditField.Value;
            %使用默认设定
            value = app.thetragethistogramDropDown.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'self-defined'
                    [filename,path]=uigetfile({'*.mat'},'选择mat文件');
                    app.target_hist=[path,filename];  
                    uialert(app.UIFigure,'The target_hist has been saved!','Notice','Icon','success') 
            end
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success') 
        end

        % Callback function
        function thetragethistogramDropDownValueChanged(app, event)
            value = app.thetragethistogramDropDown.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist='Target_Hist_TubeTK.mat';
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                case 'self-defined'
                    [filename,path]=uigetfile({'*.mat'},'选择mat文件');
                    app.target_hist=[path,filename];
            end
            
        end

        % Callback function
        function startButton_2Pushed(app, event)
            %进度条显示处理进度
            progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on histogram matching','Value',0);
            info=load_untouch_nii(app.input_img_path);
            hdr=info.hdr;
            % 直方图规定化
            Trans_Image=histMatching(app.input_img_path,app.target_hist);
            trans_img = make_nii(Trans_Image);
            trans_img.hdr =hdr;
            %保存规定化图片
            save_path=fullfile(app.output_path,['Matched_',app.output_suffix,'.nii.gz']);
            save_nii(trans_img, save_path);
            
            %进度条更新
            progress_bar.Value=1/4;
            progress_bar.Message=sprintf('Working on vessel enhance');
            
            %vessel enhance
            IMG=info.img;
            spacing=hdr.dime.pixdim(2:4);%分辨率
            I = IMG - min(IMG(:));
            I = I / prctile(I(I(:) > 0.5 * max(I(:))),90);
            I(I>1) = 1;
            voxSpace=min(spacing);%单位体素
            tau = 1;
            vesselness = vesselness3D(I, [0.5:0.5:3], [1;1;1], tau, true);
            Mask=imbinarize(IMG);
            Mask=double(Mask); 
            vesselness=vesselness.*Mask;

            vessel_img=make_nii(vesselness);
            vessel_img.hdr = hdr; 
            path=fullfile(app.output_path,['Vesselness_',app.output_suffix,'.nii.gz']);
            save_nii(vessel_img, path);


            %进度条更新
            progress_bar.Value=2/4;
            progress_bar.Message=sprintf('Working on fast fuzzy C-means segmentation');
            
            %fFCM
            img=int32(Trans_Image(Trans_Image~=0));
%             FCM
            k=4;
            [C,U,LUT,H]=FastFCMeans(img(:),k,app.m_value,false) ;% perform FCM segmentation
            membership_mat=U;
            L=LUT2label(img,LUT);
            % Get FCM results获得FCM处理后的结果
            FCM_seg=zeros(size(Trans_Image));
            OriginId=find(Trans_Image~=0);
            FCM_seg(OriginId(L(:)==k))=1; 
            FCM_seg(OriginId(L(:)==k-1))=1; 
            % W is ratio of each class
            W = zeros(1,k);
            for c = 1:4
                W(c) = length(find(L(:) == c)) / numel(Trans_Image(Trans_Image ~= 0));
            end
            W_mat=W;
            FCM_img=make_nii(FCM_seg);
            FCM_img.hdr = hdr; 
            path=fullfile(app.output_path,['FCM_seg_',app.output_suffix,'.nii.gz']);
            save_nii(FCM_img, path);
            path=fullfile(app.output_path,['FCM_seg_',app.output_suffix,'_membership.mat']);
            save(path,'membership_mat');
            path=fullfile(app.output_path,['FCM_seg_',app.output_suffix,'_radio_W.mat']);
            save(path,'W_mat');
            
            %进度条更新
            progress_bar.Value=3/4;
            progress_bar.Message=sprintf('Working on Markov Random Field segmentation');
            
            %MRF
            beta = 0.2;
            IL = 10;
            [MRF_seg] = MRF(FCM_seg, Trans_Image, vesselness, membership_mat, beta, W_mat, IL);
            
            %% remove little islands  跟师兄说一下这个代码不见了
            [MRF_seg_refine, ~, ~] = Connection_Judge_3D(MRF_seg, 0, [], 20, 3);

            app.output_img=make_nii(MRF_seg_refine);
            app.output_img.hdr = hdr; 
            path=fullfile(app.output_path,['MRF_seg_',app.output_suffix,'.nii.gz']);
            save_nii(app.output_img, path);
            %进度条更新
            progress_bar.Value=1;
            close(progress_bar);
 
            uialert(app.UIFigure,"Segmetation is complete,the 'MRF_*.nii.gz' is the final result. " + ...
                "All images are saved in the folder!",'Notice','Icon','success')
            clear
        end

        % Selection changed function: imagepathsButtonGroup
        function imagepathsButtonGroupSelectionChanged(app, event)
            selectedButton = app.imagepathsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'path of vascular image'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.vessel_EditField_2.Value=[path,filename];
                    app.seg_path=app.vessel_EditField_2.Value;
                case 'path of save stat'
                    app.stat_path = uigetdir();
                    app.save_stat_EditField.Value=app.stat_path;
                case 'path of brain mask'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.brain_mask_EditField_3.Value=[path,filename];
                    app.brain_mask_path=app.brain_mask_EditField_3.Value;
            end
        end

        % Value changed function: 
        % TherangeofvascularstatisticsDropDown
        function TherangeofvascularstatisticsDropDownValueChanged(app, event)
            value = app.TherangeofvascularstatisticsDropDown.Value;
            switch value
                case 'whole brain'
                    app.is_atlas=false;
                    app.MeasurementofvascularindifferentbrainnetworksPanel.Visible='off';
                case 'multiple brain networks'
                    app.is_atlas=true;
                    app.MeasurementofvascularindifferentbrainnetworksPanel.Visible='on';
            end 
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.atlas_prefix=app.atlasprefixEditField.Value;
            app.atlas_prefix=py.str(app.atlas_prefix);
            app.atlas_ranges=app.atlasrangesEditField.Value;
            app.atlas_ranges=py.int(app.atlas_ranges);
            list=app.atlasnameslistTextArea.Value;
            split=',';%d逗号分隔符
            app.atlas_names_list= regexp(list,split,'split');
            app.atlas_names_list=py.list(app.atlas_names_list);
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
             
        end

        % Button pushed function: pathofatlasButton
        function pathofatlasButtonPushed(app, event)
            [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
            app.path_atlas_EditField_2.Value=[path,filename];
            app.atlas_path=app.path_atlas_EditField_2.Value;
            app.atlas_path=py.str(app.atlas_path);
        end

        % Callback function
        function pathofinputimagesButtonPushed(app, event)
            app.input_dir_path = uigetdir();
            app.image_to_seg_EditField.Value=app.input_dir_path;
        end

        % Callback function
        function saveparamsButton_4Pushed(app, event)
            app.outputs_prefix=app.outputsprefixEditField.Value; 
            app.inputs_prefix=app.inputsprefixEditField.Value;
            app.m_value=app.FCMparametermEditField_2.Value;
            %使用默认设定
            value = app.thetragethistogramDropDown_2.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'self-defined'
                    [filename,path]=uigetfile({'*.mat'},'选择mat文件');
                    app.target_hist=[path,filename];  
                    uialert(app.UIFigure,'The target_hist has been saved!','Notice','Icon','success') 
            end
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success') 

        end

        % Callback function
        function thetragethistogramDropDown_2ValueChanged(app, event)
            value = app.thetragethistogramDropDown_2.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist='Target_Hist_TubeTK.mat';
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                case 'self-defined'
                    [filename,path]=uigetfile({'*.mat'},'选择mat文件');
                    app.target_hist=[path,filename];        
            end
            uialert(app.UIFigure,'The target_hist has been saved!','Notice','Icon','success') 
        end

        % Callback function
        function startButton_7Pushed(app, event)
                        
            [files_name,files_path]=file_extraction(app.input_dir_path, app.inputs_prefix);
            if isempty(files_name)
                alart_message='There is not the images to be processed in the document!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(files_name)
                    % 文件名准备、保存文件名后缀准备
                    file=files_name{i};
                    info=load_untouch_nii(file);
                    hdr=info.hdr;
                    [~, endindex]= regexp(file,app.inputs_prefix);
                    disp(file)
                    disp(endindex)
                    suffix = file(endindex+1:end);
                    % 直方图规定化
                    Trans_Image=histMatching(file,app.target_hist);
                    trans_img = make_nii(Trans_Image);
                    trans_img.hdr =hdr;
                    %保存规定化图片
                    save_path=fullfile(files_path{i},['Matched',suffix]);
                    save_nii(trans_img, save_path);
             
                    %vessel enhance
                    IMG=info.img;
                    spacing=hdr.dime.pixdim(2:4);%分辨率
                    I = IMG - min(IMG(:));
                    I = I / prctile(I(I(:) > 0.5 * max(I(:))),90);
                    I(I>1) = 1;
                    voxSpace=min(spacing);%单位体素
                    tau = 1;
                    vesselness = vesselness3D(I, [0.5:0.5:3], [1;1;1], tau, true);
                    Mask=imbinarize(IMG);
                    Mask=double(Mask); 
                    vesselness=vesselness.*Mask;
                    % 保存图片
                    vessel_img=make_nii(vesselness);
                    vessel_img.hdr = hdr; 
                    save_path=fullfile(files_path{i},['Vesselness',suffix]);
                    save_nii(vessel_img, save_path);
                        
                    %fFCM
                    img=int32(Trans_Image(Trans_Image~=0));
        %             FCM
                    k=4;
                    [C,U,LUT,H]=FastFCMeans(img(:),k,app.m_value,false) ;% perform FCM segmentation
                    membership_mat=U;
                    L=LUT2label(img,LUT);
                    % Get FCM results获得FCM处理后的结果
                    FCM_seg=zeros(size(Trans_Image));
                    OriginId=find(Trans_Image~=0);
                    FCM_seg(OriginId(L(:)==k))=1; 
                    FCM_seg(OriginId(L(:)==k-1))=1; 
                    % W is ratio of each class
                    W = zeros(1,k);
                    for c = 1:4
                        W(c) = length(find(L(:) == c)) / numel(Trans_Image(Trans_Image ~= 0));
                    end
                    W_mat=W;
                    FCM_img=make_nii(FCM_seg);
                    FCM_img.hdr = hdr; 
                    path=fullfile(files_path{i},['FCM_seg',suffix]);
                    save_nii(FCM_img, path);
                    sub_suffix=suffix(1:(end-7));% 去掉'.nii.gz'
                    path=fullfile(files_path{i},['FCM_seg',sub_suffix,'_membership.mat']);
                    save(path,'membership_mat');
                    path=fullfile(files_path{i},['FCM_seg',sub_suffix,'_radio_W.mat']);
                    save(path,'W_mat');
                    
                    %MRF
                    beta = 0.2;
                    IL = 10;
                    [MRF_seg] = MRF(FCM_seg, Trans_Image, vesselness, membership_mat, beta, W_mat, IL);
                    
                    %% remove little islands
                    [MRF_seg_refine, ~, ~] = Connection_Judge_3D(MRF_seg, 0, [], 20, 3);
        
                    app.output_img=make_nii(MRF_seg_refine);
                    app.output_img.hdr = hdr; 
                    path=fullfile(files_path{i},[app.outputs_prefix,suffix]);
                    save_nii(app.output_img, path);
                    %进度条更新
                    progress_bar.Value=i/length(files_name);
                    if i~=length(files_name)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
            end

            uialert(app.UIFigure,['Segmentation is complete,' ...
                'and the output are saved to the corresponding path.'],'Notice','Icon','success')
        
        end

        % Button pushed function: histogrammatchingButton
        function histogrammatchingButtonPushed(app, event)
            app.hist_app=hist_parameter(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StepbystepprocessingTab = uitab(app.TabGroup);
            app.StepbystepprocessingTab.Title = 'Step-by-step processing';
            app.imagepreprocessingPanel = uipanel(app.StepbystepprocessingTab);
            app.imagepreprocessingPanel.Title = 'image pre-processing';
            app.skullstrippingButton = uibutton(app.imagepreprocessingPanel, 'push');
            app.skullstrippingButton.ButtonPushedFcn = createCallbackFcn(app, @skullstrippingButtonPushed, true);
            app.skullstrippingButton.Text = 'skull-stripping';
            app.imagedenoiseButton = uibutton(app.imagepreprocessingPanel, 'push');
            app.imagedenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @imagedenoiseButtonPushed, true);
            app.imagedenoiseButton.Text = 'image denoise';
            app.N4FieldCorrectionButton = uibutton(app.imagepreprocessingPanel, 'push');
            app.N4FieldCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @N4FieldCorrectionButtonPushed, true);
            app.N4FieldCorrectionButton.Text = 'N4FieldCorrection';
            app.vesselenhancementButton = uibutton(app.imagepreprocessingPanel, 'push');
            app.vesselenhancementButton.ButtonPushedFcn = createCallbackFcn(app, @vesselenhancementButtonPushed, true);
            app.vesselenhancementButton.Text = 'vessel enhancement';
            app.BatchpreprocessingPanel = uipanel(app.StepbystepprocessingTab);
            app.BatchpreprocessingPanel.Title = 'Batch pre-processing';
            app.histogrammatchingButton = uibutton(app.BatchpreprocessingPanel, 'push');
            app.histogrammatchingButton.ButtonPushedFcn = createCallbackFcn(app, @histogrammatchingButtonPushed, true);
            app.histogrammatchingButton.Text = 'histogram matching';
            app.vesselsegmentationPanel = uipanel(app.StepbystepprocessingTab);
            app.vesselsegmentationPanel.Title = 'vessel segmentation';
            app.FFCMMRFfastfuzzyCmeansMarkovRandomFieldButton = uibutton(app.vesselsegmentationPanel, 'push');
            app.FFCMMRFfastfuzzyCmeansMarkovRandomFieldButton.ButtonPushedFcn = createCallbackFcn(app, @FFCMMRFfastfuzzyCmeansMarkovRandomFieldButtonPushed, true);
            app.FFCMMRFfastfuzzyCmeansMarkovRandomFieldButton.Text = 'FFCM-MRF(fast fuzzy C means-Markov Random Field)';
            app.MeasurementofvascularindicatorsTab = uitab(app.TabGroup);
            app.MeasurementofvascularindicatorsTab.Title = 'Measurement of vascular indicators';
            app.TabGroup2 = uitabgroup(app.MeasurementofvascularindicatorsTab);
            app.SingleimageTab = uitab(app.TabGroup2);
            app.SingleimageTab.Title = 'Single image';
            app.MeasurementPanel = uipanel(app.SingleimageTab);
            app.MeasurementPanel.Title = 'Measurement';
            app.imagepathsButtonGroup = uibuttongroup(app.MeasurementPanel);
            app.imagepathsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imagepathsButtonGroupSelectionChanged, true);
            app.imagepathsButtonGroup.Title = 'image paths';
            app.pathofsavestatButton = uitogglebutton(app.imagepathsButtonGroup);
            app.pathofsavestatButton.Text = 'path of save stat';
            app.pathofsavestatButton.Value = true;
            app.pathofvascularimageButton = uitogglebutton(app.imagepathsButtonGroup);
            app.pathofvascularimageButton.Text = 'path of vascular image';
            app.pathofbrainmaskButton = uitogglebutton(app.imagepathsButtonGroup);
            app.pathofbrainmaskButton.Text = 'path of brain mask';
            app.save_stat_EditField = uieditfield(app.imagepathsButtonGroup, 'text');
            app.vessel_EditField_2 = uieditfield(app.imagepathsButtonGroup, 'text');
            app.brain_mask_EditField_3 = uieditfield(app.imagepathsButtonGroup, 'text');
            app.TherangeofvascularstatisticsDropDownLabel = uilabel(app.imagepathsButtonGroup);
            app.TherangeofvascularstatisticsDropDownLabel.Text = 'The range of vascular statistics';
            app.TherangeofvascularstatisticsDropDown = uidropdown(app.imagepathsButtonGroup);
            app.TherangeofvascularstatisticsDropDown.Items = {'whole brain', 'multiple brain networks'};
            app.TherangeofvascularstatisticsDropDown.ValueChangedFcn = createCallbackFcn(app, @TherangeofvascularstatisticsDropDownValueChanged, true);
            app.TherangeofvascularstatisticsDropDown.Value = 'whole brain';
            app.startButton_3 = uibutton(app.imagepathsButtonGroup, 'push');
            app.startButton_3.Text = 'start';
            app.MeasurementofvascularindifferentbrainnetworksPanel = uipanel(app.MeasurementPanel);
            app.MeasurementofvascularindifferentbrainnetworksPanel.Title = 'Measurement of vascular in different brain networks';
            app.pathofatlasButton = uibutton(app.MeasurementofvascularindifferentbrainnetworksPanel, 'push');
            app.pathofatlasButton.ButtonPushedFcn = createCallbackFcn(app, @pathofatlasButtonPushed, true);
            app.pathofatlasButton.Text = 'path of atlas';
            app.path_atlas_EditField_2 = uieditfield(app.MeasurementofvascularindifferentbrainnetworksPanel, 'text');
            app.saveallButton = uibutton(app.MeasurementofvascularindifferentbrainnetworksPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.pathofatlastxtButton = uibutton(app.MeasurementofvascularindifferentbrainnetworksPanel, 'push');
            app.pathofatlastxtButton.Text = 'path of atlas txt';
            app.path_atlas_EditField_3 = uieditfield(app.MeasurementofvascularindifferentbrainnetworksPanel, 'text');
            app.helpTextAreaLabel = uilabel(app.MeasurementPanel);
            app.helpTextAreaLabel.Text = 'help ';
            app.helpTextArea = uitextarea(app.MeasurementPanel);
            app.BatchimagesTab = uitab(app.TabGroup2);
            app.BatchimagesTab.Title = 'Batch images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_process

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end