classdef hist_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        helponhistogrammatchingPanel    matlab.ui.container.Panel
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        SingleimageprocessingTab        matlab.ui.container.Tab
        currentmodulehistogrammatchingPanel  matlab.ui.container.Panel
        imageButtonGroup                matlab.ui.container.ButtonGroup
        imagetohistogrammatchingButton  matlab.ui.control.ToggleButton
        image_to_histogram_matching_EditField  matlab.ui.control.EditField
        pathofsaveimageButton           matlab.ui.control.ToggleButton
        path_of_save_image_EditField    matlab.ui.control.EditField
        startButton                     matlab.ui.control.Button
        parametersPanel                 matlab.ui.container.Panel
        setthetragethistogramDropDownLabel  matlab.ui.control.Label
        setthetragethistogramDropDown   matlab.ui.control.DropDown
        outputfilenameEditFieldLabel    matlab.ui.control.Label
        filename_prefix_EditField       matlab.ui.control.EditField
        saveallButton                   matlab.ui.control.Button
        BatchimageprocessingTab         matlab.ui.container.Tab
        currentmodulehistogrammatchingPanel_2  matlab.ui.container.Panel
        imageButtonGroup_2              matlab.ui.container.ButtonGroup
        pathofinputimagesButton         matlab.ui.control.ToggleButton
        image_to_histogram_matching_EditField_2  matlab.ui.control.EditField
        pathofsaveimagesButton          matlab.ui.control.ToggleButton
        path_of_save_image_EditField_2  matlab.ui.control.EditField
        startButton_2                   matlab.ui.control.Button
        parametersPanel_2               matlab.ui.container.Panel
        setthetragethistogramDropDown_2Label  matlab.ui.control.Label
        setthetragethistogramDropDown_2  matlab.ui.control.DropDown
        outputprefixLabel               matlab.ui.control.Label
        filename_prefix_EditField_2     matlab.ui.control.EditField
        saveallButton_2                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        target_hist
        input_img_path
        output_img
        output_prefix
        output_path
        input_dir_path
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";
            app.filename_prefix_EditField_2.Value="Please enter the prefix of the saved file";
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.filename_prefix_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to histogram matching'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_histogram_matching_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_histogram_matching_EditField.Value;
                    app.TextArea.Value="请单击按钮'image to histogram matching'导入要进行直方图规定化的图像，仅限于NIFTI格式图像";
                case 'path of save image'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField.Value=app.output_path;
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            Trans_Image=histMatching(app.input_img_path,app.target_hist);
            app.output_img = make_nii(Trans_Image);
            input_data=load_untouch_nii(app.input_img_path);
            app.output_img.hdr = input_data.hdr;
            path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            save_nii(app.output_img, path);
            uialert(app.UIFigure,['Histogram matching is complete,' ...
                'and the output image is saved to the corresponding path.'],'Notice','Icon','success')
            
        end

        % Value changed function: setthetragethistogramDropDown
        function setthetragethistogramDropDownValueChanged(app, event)
            value = app.setthetragethistogramDropDown.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist='Target_Hist_TubeTK.mat';
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                otherwise   
            end
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            value = app.setthetragethistogramDropDown.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist=Target_Hist_TubeTK;
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                otherwise   
            end
            app.output_prefix=app.filename_prefix_EditField.Value;
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success') 
        end

        % Selection changed function: imageButtonGroup_2
        function imageButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.imageButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'path of input images'
                    app.input_dir_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.image_to_histogram_matching_EditField_2.Value=app.input_dir_path;
                    app.TextArea.Value="请单击按钮'image to histogram matching'导入要进行直方图规定化的图像，仅限于NIFTI格式图像";
                case 'path of save images'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField_2.Value=app.output_path;
            end
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            value = app.setthetragethistogramDropDown_2.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist=Target_Hist_TubeTK;
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                otherwise   
            end
            app.output_prefix=app.filename_prefix_EditField_2.Value;
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success') 
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            file_list=dir(app.input_dir_path);
            %进度条显示处理进度
            progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
            for i=1:length(file_list)%循环处理图像
                if i==1%跳过前两个空文件夹
                    continue
                elseif i==2
                    continue
                end
                file_name=fullfile(app.input_dir_path,file_list(i).name);
                Trans_Image=histMatching(file_name,app.target_hist);
                app.output_img = make_nii(Trans_Image);
                input_data=load_untouch_nii(file_name);
                app.output_img.hdr = input_data.hdr;
                path=[app.output_path,'\',app.output_prefix,'_',file_list(i).name];
                save_nii(app.output_img, path);
                progress_bar.Value=(i-2)/(length(file_list)-2);
                if i~=length(file_list)
                    progress_bar.Message=sprintf('Working on image %d',i-1);
                else%关闭进度条
                    close(progress_bar);
                end
            end
            uialert(app.UIFigure,['Histogram matching is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponhistogrammatchingPanel = uipanel(app.UIFigure);
            app.helponhistogrammatchingPanel.Title = 'help on histogram matching';
            app.TextAreaLabel = uilabel(app.helponhistogrammatchingPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponhistogrammatchingPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmodulehistogrammatchingPanel = uipanel(app.SingleimageprocessingTab);
            app.currentmodulehistogrammatchingPanel.Title = 'current module:histogram matching';
            app.imageButtonGroup = uibuttongroup(app.currentmodulehistogrammatchingPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetohistogrammatchingButton = uitogglebutton(app.imageButtonGroup);
            app.imagetohistogrammatchingButton.Text = 'image to histogram matching';
            app.imagetohistogrammatchingButton.Value = true;
            app.image_to_histogram_matching_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmodulehistogrammatchingPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.parametersPanel = uipanel(app.currentmodulehistogrammatchingPanel);
            app.parametersPanel.Title = 'parameters';
            app.setthetragethistogramDropDownLabel = uilabel(app.parametersPanel);
            app.setthetragethistogramDropDownLabel.Text = 'set the traget histogram';
            app.setthetragethistogramDropDown = uidropdown(app.parametersPanel);
            app.setthetragethistogramDropDown.Items = {'MIDAS', 'TubeTK', 'Option 3', 'Option 4'};
            app.setthetragethistogramDropDown.ValueChangedFcn = createCallbackFcn(app, @setthetragethistogramDropDownValueChanged, true);
            app.setthetragethistogramDropDown.Value = 'MIDAS';
            app.outputfilenameEditFieldLabel = uilabel(app.parametersPanel);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.parametersPanel, 'text');
            app.saveallButton = uibutton(app.parametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmodulehistogrammatchingPanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmodulehistogrammatchingPanel_2.Title = 'current module:histogram matching';
            app.imageButtonGroup_2 = uibuttongroup(app.currentmodulehistogrammatchingPanel_2);
            app.imageButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroup_2SelectionChanged, true);
            app.imageButtonGroup_2.Title = 'image';
            app.pathofinputimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.pathofinputimagesButton.Value = true;
            app.image_to_histogram_matching_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.pathofsaveimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofsaveimagesButton.Text = 'path of save images';
            app.path_of_save_image_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.startButton_2 = uibutton(app.currentmodulehistogrammatchingPanel_2, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.parametersPanel_2 = uipanel(app.currentmodulehistogrammatchingPanel_2);
            app.parametersPanel_2.Title = 'parameters';
            app.setthetragethistogramDropDown_2Label = uilabel(app.parametersPanel_2);
            app.setthetragethistogramDropDown_2Label.Text = 'set the traget histogram';
            app.setthetragethistogramDropDown_2 = uidropdown(app.parametersPanel_2);
            app.setthetragethistogramDropDown_2.Items = {'MIDAS', 'TubeTK', 'Option 3', 'Option 4'};
            app.setthetragethistogramDropDown_2.Value = 'MIDAS';
            app.outputprefixLabel = uilabel(app.parametersPanel_2);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField_2 = uieditfield(app.parametersPanel_2, 'text');
            app.saveallButton_2 = uibutton(app.parametersPanel_2, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hist_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end